classdef accellerator_ottobre2026 < matlab.apps.AppBase
    %ACCELLERATOR_OTTOBRE2026 Sistema di dosaggio a due pompe
    %   Questa applicazione consente di controllare due pompe peristaltiche
    %   tramite comunicazione seriale UART con un microcontrollore.
    %   
    %   --- FLUSSO DI LAVORO ---
    %   1. Collega il microcontrollore via USB
    %   2. Premi "Connessione" per stabilire il collegamento seriale
    %   3. Imposta le portate (Portata 1 / Portata 2) o il TFR e FRR
    %   4. Imposta le masse massime nel pannello "Volumi massimi"
    %   5. Attiva "Start Logging" per registrare i dati su file CSV
    %   6. Premi "Start Production" per avviare il dosaggio
    %   7. Al termine, premi "Salva" per esportare i dati in Excel
    %   
    %   --- PROTOCOLLO SERIALE ---
    %   Baud rate: 115200
    %   Pacchetto RX: [A] [9 float32 = 36 byte] [LF]  →  38 byte totali
    %   Valori ricevuti: dt, q1, V1, q2, V2, flusso1, flusso2, massa1,
    %   massa2
    %   
    %   --- REQUISITI ---
    %   MATLAB R2021a+  |  Instrument Control Toolbox
    %   
    %   Per assistenza contattare l'autore del progetto.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        Lamp                           matlab.ui.control.Lamp
        FunzionamentoPanel             matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        LaunchPanel                    matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        StartProductionButton          matlab.ui.control.Button
        SalvaButton                    matlab.ui.control.Button
        portate                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Portata2EditField              matlab.ui.control.NumericEditField
        Portata1EditField              matlab.ui.control.NumericEditField
        Flusso2Label                   matlab.ui.control.Label
        Flusso1Label                   matlab.ui.control.Label
        STOPButton                     matlab.ui.control.Button
        StartLoggingButton             matlab.ui.control.StateButton
        SensorModeSwitch               matlab.ui.control.RockerSwitch
        portate_2                      matlab.ui.container.Panel
        GridLayout3_2                  matlab.ui.container.GridLayout
        FlowRateRatioEditField         matlab.ui.control.NumericEditField
        TotalFlowRateEditField         matlab.ui.control.NumericEditField
        FlowRateRatioLabel             matlab.ui.control.Label
        TotalFlowRateLabel             matlab.ui.control.Label
        FunzioneSwitch                 matlab.ui.control.RockerSwitch
        TabGroup                       matlab.ui.container.TabGroup
        PortateerogateTab              matlab.ui.container.Tab
        GridLayout4_2                  matlab.ui.container.GridLayout
        VolumimassimiPanel             matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        ImpostavolumimassimiButton     matlab.ui.control.Button
        ResetMassButton                matlab.ui.control.Button
        MassaTotEditField              matlab.ui.control.NumericEditField
        MassaTotEditFieldLabel         matlab.ui.control.Label
        Massa2EditField                matlab.ui.control.NumericEditField
        Massa2EditFieldLabel           matlab.ui.control.Label
        Massa1EditField                matlab.ui.control.NumericEditField
        Massa1EditFieldLabel           matlab.ui.control.Label
        VolumiErogatiPanel             matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        TempoLabel                     matlab.ui.control.Label
        TimeGauge                      matlab.ui.control.LinearGauge
        TimeEditField                  matlab.ui.control.NumericEditField
        VolumetotGaugeLabel            matlab.ui.control.Label
        VolumeTotGauge                 matlab.ui.control.LinearGauge
        VolumeTotEditField             matlab.ui.control.NumericEditField
        Volume2GaugeLabel              matlab.ui.control.Label
        Volume2Gauge                   matlab.ui.control.LinearGauge
        Volume2EditField               matlab.ui.control.NumericEditField
        Volume1GaugeLabel              matlab.ui.control.Label
        Volume1Gauge                   matlab.ui.control.LinearGauge
        Volume1EditField               matlab.ui.control.NumericEditField
        GraficoTab                     matlab.ui.container.Tab
        GridLayout17                   matlab.ui.container.GridLayout
        UIAxes_values2                 matlab.ui.control.UIAxes
        UIAxes_values                  matlab.ui.control.UIAxes
        Regressione                    matlab.ui.container.Tab
        GridLayout10                   matlab.ui.container.GridLayout
        Flussimetro2Panel              matlab.ui.container.Panel
        GridLayout19_2                 matlab.ui.container.GridLayout
        D2EditField                    matlab.ui.control.NumericEditField
        D2Label                        matlab.ui.control.Label
        C2EditField                    matlab.ui.control.NumericEditField
        C2Label                        matlab.ui.control.Label
        mHigh_real_2EditField          matlab.ui.control.NumericEditField
        mLow_real_2EditField           matlab.ui.control.NumericEditField
        MassRealLabel_2                matlab.ui.control.Label
        mHigh_hyp_2EditField           matlab.ui.control.NumericEditField
        mLow_hyp_2EditField            matlab.ui.control.NumericEditField
        MassHypLabel_2                 matlab.ui.control.Label
        Qhigh_2EditField               matlab.ui.control.NumericEditField
        Qlow_2EditField                matlab.ui.control.NumericEditField
        FLOWSETRAWLabel_2              matlab.ui.control.Label
        HIGHFLOWLabel_2                matlab.ui.control.Label
        LOWFLOWLabel_2                 matlab.ui.control.Label
        ImpostadatiPIDButton           matlab.ui.control.Button
        CalibrationDataButton          matlab.ui.control.Button
        PIDSETTINGSPanel               matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        SmorzamentoOscillazioneLabel   matlab.ui.control.Label
        EliminaErroreStazionarioLabel  matlab.ui.control.Label
        rispostaImmediataLabel         matlab.ui.control.Label
        kd                             matlab.ui.control.NumericEditField
        ki                             matlab.ui.control.NumericEditField
        kp                             matlab.ui.control.NumericEditField
        alfaLabel_4                    matlab.ui.control.Label
        alfaLabel_3                    matlab.ui.control.Label
        alfaLabel_2                    matlab.ui.control.Label
        Flussimetro1Panel              matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        D1EditField                    matlab.ui.control.NumericEditField
        C1EditField                    matlab.ui.control.NumericEditField
        D1EditFieldLabel               matlab.ui.control.Label
        alfaLabel                      matlab.ui.control.Label
        mHigh_real_1EditField          matlab.ui.control.NumericEditField
        mLow_real_1EditField           matlab.ui.control.NumericEditField
        MassRealLabel                  matlab.ui.control.Label
        mHigh_hyp_1EditField           matlab.ui.control.NumericEditField
        mLow_hyp_1EditField            matlab.ui.control.NumericEditField
        MassHypLabel                   matlab.ui.control.Label
        Qhigh_1EditField               matlab.ui.control.NumericEditField
        Qlow_1EditField                matlab.ui.control.NumericEditField
        FLOWSETRAWLabel                matlab.ui.control.Label
        HIGHFLOWLabel                  matlab.ui.control.Label
        LOWFLOWLabel                   matlab.ui.control.Label
        utK_petK_iint_0tetaudtauK_dfracdetdtLabel  matlab.ui.control.Label
        FlowCalibratedtextbfCcdotFlowRawtextbfDLabel  matlab.ui.control.Label
        pidValuesText                  matlab.ui.control.Label
        FattoreCorrezioneText          matlab.ui.control.Label
        ConnessionePanel               matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ConnessioneButton              matlab.ui.control.StateButton
        listaSeriale                   matlab.ui.control.DropDown
        ListaBleLabel_2                matlab.ui.control.Label
        ThemeButton                    matlab.ui.control.Button
    end

    properties (Access = private)
        theme=1;
        values                 % Ultimi 9 valori float ricevuti via seriale: [dt, q1, V1, q2, V2, f1, f2, m1, m2]
        mass=zeros(3,1);       % Vettore masse [m1, m2, mtot] — azzerato a ogni reset
        fid = -1               % File descriptor per il logging CSV (-1 = file chiuso)
        t;                     % Tempo corrente del grafico, incrementato ogni tick del timer
        % --- Seriale ---
        timerObj_uart;         % Timer MATLAB per il refresh del grafico a intervalli fissi
        ardObj;                % Oggetto serialport per la comunicazione con Arduino

        % --- Acquisizione ---

        animLine_f1            % Linea animata del grafico pompa 1
        animLine_f2            % Linea animata del grafico pompa 2
        dt=0.2;                % Periodo del timer in secondi (= frequenza di aggiornamento grafico)

        filePath               % Percorso del file CSV temporaneo di logging
        HLine_f1;              % Linea orizzontale setpoint pompa 1 sul grafico
        HLine_f2;              % Linea orizzontale setpoint pompa 2 sul grafico
    end

    methods (Access = private)

        function disconnection(app)
            % Disconnette il dispositivo seriale e ferma il timer di aggiornamento
            try
                clear app.ardObj                        % Rilascia la porta seriale
                app.ardObj=[];
                app.FunzionamentoPanel.Enable = "off";  % Disabilita la UI di controllo
                stop(app.timerObj_uart);                % Ferma il timer
                %delete(app.timerObj_uart);              % Distrugge l'oggetto timer
                %app.timerObj_uart = [];                 % Azzera il riferimento
            catch
                uialert(app.UIFigure, 'Errore durante disconnessione.', 'Errore');
            end
        end

        function timer_plot(app)
            % Azzera il contatore temporale e avvia il timer di aggiornamento grafico
            try
                app.t = 0;
                start(app.timerObj_uart);
            catch
                uialert(app.UIFigure, 'timer_plot', 'Errore');
            end
        end

        % Indici valori ricevuti dal firmware:
        % [dt, q1, V1, q2, V2, flusso1, flusso2, massa1, massa2]
        %   1   2   3   4   5     6       7         8       9
        function updatePlot(app)
            % Chiamata dal timer ogni dt secondi: aggiorna text area, gauge e grafici

            % app.SerialeTextArea.Value = num2str(app.values);%,'%.3f '); % Mostra i raw data

            try

                app.UITable.Data =app.values;
            catch exception

            end
            if app.StartLoggingButton.Value  % Aggiorna solo se il logging è attivo
                if isempty(app.t)
                    app.t = 0;
                end

                try
                    %app.SerialeTextArea.Value = num2str(app.values);
                    f1 = app.values(6);                           % Flusso misurato pompa 1
                    f2 = app.values(7);                           % Flusso misurato pompa 2
                     app.Volume1Gauge.Value    = app.values(8);    % Massa erogata pompa 1
                     app.Volume2Gauge.Value    = app.values(9);    % Massa erogata pompa 2
                     app.VolumeTotGauge.Value  = app.values(8) + app.values(9); % Massa totale
                    %
                    %
                    % app.Volume1EditField.Value    = app.values(8);    % Massa erogata pompa 1
                    % app.Volume2EditField.Value    = app.values(9);    % Massa erogata pompa 2
                    % app.VolumeTotEditField.Value  = app.values(8) + app.values(9); % Massa totale
                catch
                    f1 = 0; f2 = 0; % Valori di sicurezza in caso di dati mancanti
                end

                addpoints(app.animLine_f1, app.t, f1); % Aggiunge punto grafico pompa 1
                addpoints(app.animLine_f2, app.t, f2); % Aggiunge punto grafico pompa 2
                drawnow limitrate nocallbacks           % Aggiorna il rendering senza bloccare la UI

                app.t = app.t + app.dt;        % Avanza il tempo di un passo
                app.TimeGauge.Value = app.t;   % Aggiorna il gauge del tempo
            end

        end

        function selection_datas(app)

            mode=str2double(app.FunzioneSwitch.Value);
            try
                switch mode

                    case 0
                        app.portate.Enable   = "on";
                        app.portate_2.Enable = "off";

                        Q1 = app.Portata1EditField.Value;
                        Q2 = app.Portata2EditField.Value;
                        [~, ~, TFR, FRR]  =flowConversion(app,Q1,Q2,2);
                        app.TotalFlowRateEditField.Value=TFR;
                        app.FlowRateRatioEditField.Value=FRR;

                    case 1
                        app.portate.Enable   = "off";
                        app.portate_2.Enable = "on";
                        TFR = app.TotalFlowRateEditField.Value;
                        FRR = app.FlowRateRatioEditField.Value;
                        [Q1, Q2, ~, ~] = flowConversion(app,TFR, FRR, 1);
                        app.Portata1EditField.Value=Q1;
                        app.Portata2EditField.Value=Q2;


                    otherwise
                        % error('Mode deve essere 1 oppure 2');

                end

            catch
            end
        end

        function ard_sendqmass(app, q1, q2, m1, m2)
            % Invia al firmware: modalità sensore, portate e masse massime
            % Aggiorna anche le linee di setpoint sui grafici
            mode   = app.SensorModeSwitch.Value;      % 0=senza sensore, 2=con sensore
            valore = single([mode q1 q2 m1 m2]);
            app.HLine_f1.Value = q1;                  % Aggiorna setpoint grafico pompa 1
            app.HLine_f1.Label = "q1 set";
            app.HLine_f2.Value = q2;                  % Aggiorna setpoint grafico pompa 2
            app.HLine_f2.Label = "q2 set";
            data_send(app, valore);
        end

        function ard_sendControl(app, setting_control, a, b, c, d)
            % Invia un pacchetto di controllo generico al firmware
            % setting_control: 0=STOP | -1=correzione flussimetri | -2=PID
            valore = single([setting_control a b c d]);
            data_send(app, valore);
        end

        function send_datas(app)

            app.t = 0;
            clearpoints(app.animLine_f1);
            clearpoints(app.animLine_f2);

            Q1 = app.Portata1EditField.Value;
            Q2 = app.Portata2EditField.Value;
            TFR = app.TotalFlowRateEditField.Value;
            FRR = app.FlowRateRatioEditField.Value;
            % ---------------------------------------------------------
            % Masse
            % ---------------------------------------------------------
            m1 = app.Massa1EditField.Value;
            m2 = app.Massa2EditField.Value;
            % ---------------------------------------------------------
            % Calcolo durata totale
            % ---------------------------------------------------------
            if TFR > 0
                duration = 60 * (m1 + m2) / TFR;
            else
                duration = 100;
            end
            app.TimeGauge.Limits = [0, duration];
            % ---------------------------------------------------------
            % Invio al firmware
            % ---------------------------------------------------------
            ard_sendqmass(app, Q1, Q2, m1, m2);
            % --- Configurazione assi grafico pompa 1 ---
            try

                app.UIAxes_values.YLim=[-0.2*Q1,2*Q1];
            catch exception

                app.UIAxes_values.YLim=[-0.2*10,2*10];
            end
            % --- Configurazione assi grafico pompa 1 ---
            try

                app.UIAxes_values2.YLim=[-0.2*Q2,2*Q2];
            catch exception

                app.UIAxes_values2.YLim=[-0.2*10,2*10];
            end
        end

        function connection_serial(app)
            % Apre la connessione seriale, configura il callback di ricezione,
            % sincronizza l'orologio e invia il comando STOP iniziale
            clear app.ardObj
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Connection', 'Indeterminate', 'on');
                COM_port  = app.listaSeriale.Value;
                BAUD_RATE = 115200;
                app.ardObj = serialport(COM_port, BAUD_RATE, "Timeout", 60);
                app.ardObj.ErrorOccurredFcn = @app.disconnessione_cheker; % Callback errore USB

                flush(app.ardObj); % Svuota il buffer seriale residuo

                % Callback attivato ogni 38 byte ricevuti
                configureCallback(app.ardObj, "byte", 38, @app.readSerialDataUART);

                app.FunzionamentoPanel.Enable = "on"; % Sblocca la UI di controllo

                % Sincronizza l'orologio interno del firmware con l'ora del PC
                c = datetime("now");
                data_send(app, [12, round(c.Hour), round(c.Minute), round(c.Second)]);
                pause(1)

                ard_sendControl(app,0, 0, 0, 0, 0); % Comando STOP di sicurezza all'avvio
                timer_plot(app);                   % Avvia il timer di aggiornamento grafico
                close(d);                          % Chiude la finestra di progresso
            catch
                uialert(app.UIFigure, 'connection_serial', 'Errore');
            end
        end

        function connection(app)
            % Wrapper per connection_serial con gestione errore aggiuntiva
            try
                connection_serial(app);
            catch
                uialert(app.UIFigure, 'connection(app)', 'Errore');
            end
        end

        function data_send(app, valore)
            % Serializza un array di valori in un pacchetto binario e lo trasmette via UART
            % Formato TX: [6 × float32 = 24 byte] + [LF 0x0A] → 25 byte totali
            try
                v      = valore;
                target = 6;
                if numel(v) >= target
                    w = v(1:target);                       % Tronca se il vettore è più lungo
                else
                    w = [v, zeros(1, target - numel(v))];  % Padding a zero se più corto
                end
                valori    = single(w);
                pacchetto = [typecast(valori, 'uint8'), uint8(10)]; % Aggiunge terminatore LF
                write(app.ardObj, pacchetto, 'uint8');
            catch
                uialert(app.UIFigure, 'data_send', 'Errore');
            end
        end

        function readSerialDataUART(app, src, ~, ~)
            % Callback seriale: legge 38 byte, verifica allineamento pacchetto,
            % decodifica i 9 float e (se attivo) scrive la riga sul file CSV

            raw = read(src, 38, "uint8"); % Legge esattamente 38 byte dal buffer
            app.Lamp.Color = [0 1 0];     % Verde = dato ricevuto

            % Verifica header 'A' (0x41) e terminatore LF (0x0A)
            if raw(1) ~= uint8('A') || raw(end) ~= uint8(10)
                % Pacchetto disallineato: cerca il prossimo header valido
                idxA = find(raw == uint8('A'), 1, 'first');
                if isempty(idxA)
                    app.Lamp.Color = [1 1 0]; % Giallo = header non trovato
                    return
                else
                    % Riallinea leggendo i byte mancanti per completare il pacchetto
                    bytesNeeded = 38 - (length(raw) - idxA + 1);
                    if bytesNeeded > 0
                        extra = read(src, bytesNeeded, "uint8");
                        raw   = [raw(idxA:end), extra(:)'];
                    else
                        raw = raw(idxA:idxA+37);
                    end
                    if raw(1) ~= uint8('A') || raw(end) ~= uint8(10)
                        app.Lamp.Color = [1 1 0]; % Giallo = ancora desincronizzato
                        return
                    end
                end
            end

            % Estrae il payload (byte 2-37 = 36 byte) e converte in 9 float32
            payload    = raw(2:37);
            app.values = typecast(uint8(payload), 'single'); % [dt,q1,V1,q2,V2,f1,f2,m1,m2]

            % Scrittura su CSV se il logging è attivo e il file è aperto
            if app.StartLoggingButton.Value && app.fid > 0
                c = datetime("now");
                fprintf(app.fid, '%02d,%02d,%06.3f,%g,%g,%g,%g,%g,%g,%g,%g,%g\n', ...
                    c.Hour, c.Minute, c.Second, ...
                    app.values(1), app.values(2), app.values(3), ...
                    app.values(4), app.values(5), app.values(6), ...
                    app.values(7), app.values(8), app.values(9));
            end
        end

        function disconnessione_cheker(app, src, event, name)
            % Callback di errore seriale: notifica l'utente e azzera l'oggetto porta
            uialert(app.UIFigure, 'Error', 'Usb Disconnection');
            app.FunzionamentoPanel.Enable="off";
            app.ardObj = [];
        end

        function TT = saveOutputData(app)
            % Esporta i dati di log dal CSV temporaneo in un file scelto dall'utente
            % Per Excel aggiunge una colonna 't' con il tempo relativo in secondi

            outPath     = app.filePath;
            outName     = 'output.xls';
            defaultName = fullfile(pwd, outName);

            [saveName, savePath] = uiputfile( ...
                {'*.xls;*.xlsx', 'Excel files (*.xls, *.xlsx)'; ...
                '*.csv',        'CSV files (*.csv)'; ...
                '*.*',          'All Files (*.*)'}, ...
                'Salva una copia come', defaultName);

            if isequal(saveName, 0) || isequal(savePath, 0)
                % L'utente ha annullato la finestra di dialogo
                uialert(app.UIFigure, 'Salvataggio annullato dall''utente.', ...
                    'saveDatas', "Icon", "warning");
            else
                [~, ~, ext] = fileparts(saveName);
                if isempty(ext)
                    ext      = '.xls';
                    saveName = [saveName, ext]; % Aggiunge estensione di default
                end
                destFull = fullfile(savePath, saveName);

                try
                    if any(strcmpi(ext, {'.xls', '.xlsx'}))
                        % Legge il CSV temporaneo, calcola il tempo relativo e salva in Excel
                        TT   = readtable(outPath);
                        time = TT.h .* 3600 + TT.m .* 60 + TT.s;
                        TT.t = time - time(1); % Tempo relativo a partire da 0
                        writetable(TT, destFull, 'FileType', 'spreadsheet');
                    else
                        copyfile(outPath, destFull); % Copia diretta per CSV e altri formati
                    end
                    msg = sprintf('Copia salvata in:\n%s', destFull);
                    uialert(app.UIFigure, msg, 'Save Data', 'Icon', 'success');
                    initLogging(app); % Azzera il file temporaneo dopo il salvataggio
                catch
                    uialert(app.UIFigure, 'Errore durante il salvataggio', 'saveDatas');
                end
            end
        end

        function initLogging(app)
            % Crea o azzera il file CSV temporaneo di logging (sovrascrittura)
            fname = fullfile(tempdir, "output.csv");
            f     = fopen(fname, "w"); % Apertura in scrittura: cancella il contenuto precedente
            fclose(f);
            app.filePath = fullfile(tempdir, 'output.csv');
        end

        function StartStopLogging(app, stato)
            % Apre il file di log in append (stato=true) o lo chiude (stato=false)
            if stato
                app.filePath = fullfile(tempdir, 'output.csv');
                app.fid      = fopen(app.filePath, 'a'); % Append: non sovrascrive i dati precedenti
                fprintf(app.fid, 'h,m,s,dt,q1,V1,q2,V2,f1,f2,m1,m2\n'); % Intestazione CSV
            else
                if app.fid > 0
                    fclose(app.fid); % Chiude e libera il file descriptor
                    app.fid = -1;
                end
            end
        end

        function porta = uart_serial_reader(app)
            % Scansiona tutte le porte seriali disponibili e restituisce
            % la prima che risponde con un byte header 'A' (dispositivo riconosciuto)
            d        = uiprogressdlg(app.UIFigure, 'Title', 'Connection', 'Indeterminate', 'on');
            baudrate = 115200;
            porte    = serialportlist();
            porta    = '';

            fprintf('Porte disponibili: %s\n', strjoin(porte, ', '));

            for i = 1:length(porte)
                p = porte(i);
                fprintf('  Provo %s ... ', p);
                try
                    s = serialport(p, baudrate, "Timeout", 1);
                    configureTerminator(s, "LF");
                    pause(0.1);   % Attende il reset di Arduino dopo la connessione
                    flush(s);
                    pause(0.5);   % Attende l'arrivo di almeno un pacchetto

                    if s.NumBytesAvailable > 0
                        b = read(s, 1, "uint8");
                        if b == uint8('A') % Header riconosciuto: dispositivo trovato
                            fprintf('trovato!\n');
                            porta = string(p);
                            delete(s);
                            close(d); % Chiude la progress dialog prima di uscire
                            return;
                        end
                    end
                    fprintf('nessun dato valido\n');
                    delete(s);
                catch
                    fprintf('non accessibile\n');
                    try delete(s); catch; end
                end
            end

            if isempty(porta)
                porta = porte; % Restituisce tutte le porte se nessun dispositivo risponde
                warning('Arduino non trovato su nessuna porta.');
            end
            close(d); % Chiude la progress dialog anche se nessuna porta è trovata
        end

        function setAppFontToConsolas(app)
            % Imposta il font 'Segoe UI' su tutti i componenti della UI che lo supportano
            % Utile per uniformare lo stile grafico dell'intera applicazione
            allComponents = findall(app.UIFigure); % Recupera tutti i componenti figli
            for i = 1:numel(allComponents)
                if isprop(allComponents(i), 'FontName') % Controlla se la proprietà esiste
                    allComponents(i).FontName = 'Segoe UI';
                end
            end
        end

        function [Q1, Q2, TFR, FRR] = flowConversion(app,value1, value2, mode)
            Q1  = NaN;
            Q2  = NaN;
            TFR = NaN;
            FRR = NaN;
            try
                switch mode

                    case 1
                        % Input: TFR e FRR
                        % Output: Q1 e Q2

                        TFR = value1;
                        FRR = value2;

                        Q2 = TFR / (1 + FRR);
                        Q1 = TFR - Q2;


                    case 2
                        % Input: Q1 e Q2
                        % Output: TFR e FRR

                        Q1 = value1;
                        Q2 = value2;

                        TFR = Q1 + Q2;
                        FRR = Q1 / Q2;


                    otherwise
                        error('Mode deve essere 1 oppure 2');

                end
            catch

            end


        end

        function [c, d] = calibrate_flow_fast(app,qset, mset, mreal)
            % CALIBRATE_FLOW_FAST  Calibrazione flussimetro con regressione lineare
            %
            % Inputs:
            %   qset  - flowrate impostato al sensore, raw  [ml/min]  (colonna A)
            %   mset  - massa ipotetica del sensore         [g]        (colonna B)
            %   mreal - massa reale misurata dalla bilancia [g]        (colonna C)
            %
            % Outputs:
            %   c  - pendenza della retta di calibrazione   (slope)
            %   d  - intercetta                             (intercept)
            %
            % Calibrazione: q_calibrato = c * q_raw + d

            % --- 1. Flusso reale dalla bilancia (formula colonna D del foglio) ---
            %   qreal = qset * mreal / mset
            qreal = qset .* mreal ./ mset;

            % --- 2. Regressione lineare: x=qset_raw, y=qreal  (LINEST Excel) ---
            p = polyfit(qset(:), qreal(:), 1);
            c = round(p(1),10);   % slope
            d = round(p(2),10);   % intercept

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % Pulisce il Command Window all'avvio

            % --- Impostazione font globale ---
            app.setAppFontToConsolas(); % Applica 'Segoe UI' a tutti i componenti della UI

            % --- Centratura e ridimensionamento della finestra ---
            screen = get(0, 'ScreenSize');   % Ottiene la risoluzione dello schermo [x y w h]
            w = screen(3) * 0.8;             % Larghezza = 80% dello schermo
            h = screen(4) * 0.8;             % Altezza   = 80% dello schermo
            x = (screen(3) - w) / 2;        % Coordinata x per centrare orizzontalmente
            y = (screen(4) - h) / 2;        % Coordinata y per centrare verticalmente
            app.UIFigure.Position = [x, y, w, h];

            % --- Inizializzazione logging ---
            initLogging(app);                       % Crea/azzera il file CSV temporaneo
            app.StartLoggingButton.Value = false;   % Logging disattivo all'avvio

            % --- Creazione del timer di aggiornamento grafico ---
            app.timerObj_uart = timer(...
                'ExecutionMode', 'fixedrate', ...            % Esecuzione a intervalli fissi
                'Period',        app.dt, ...                 % Periodo = dt (0.2 s → 5 Hz)
                'TimerFcn',      @(src,event) updatePlot(app)); % Chiama updatePlot ad ogni tick

            % --- Configurazione assi grafico pompa 1 ---
            app.UIAxes_values.XLimMode    = "auto";    % Scala X automatica
            app.UIAxes_values.XLimitMethod = "padded"; % Aggiunge margine ai bordi
            app.UIAxes_values.YLimMode    = "auto";    % Scala Y automatica

            % --- Configurazione assi grafico pompa 2 ---
            app.UIAxes_values2.XLimMode    = "auto";
            app.UIAxes_values2.XLimitMethod = "padded";
            app.UIAxes_values2.YLimMode    = "auto";

            % --- Linee animate per i grafici in tempo reale ---
            % MaximumNumPoints limita la finestra visibile a 5 secondi di dati
            app.animLine_f1 = animatedline(app.UIAxes_values,  'Color', 'b', ...
                'Marker', '*', 'MaximumNumPoints', floor(5/app.dt));
            app.animLine_f2 = animatedline(app.UIAxes_values2, 'Color', 'b', ...
                'Marker', '*', 'MaximumNumPoints', floor(5/app.dt));

            % --- Linea orizzontale setpoint pompa 1 ---
            app.HLine_f1 = yline(app.UIAxes_values, 0, '--r', 'Setpoint');
            app.HLine_f1.LineWidth = 2;
            app.HLine_f1.LabelHorizontalAlignment = 'left'; % Etichetta allineata a sinistra

            % --- Linea orizzontale setpoint pompa 2 ---
            app.HLine_f2 = yline(app.UIAxes_values2, 0, '--r', 'Setpoint');
            app.HLine_f2.LineWidth = 2;
            app.HLine_f2.LabelHorizontalAlignment = 'left';


            % --- Scansione porte seriali disponibili ---
            % uart_serial_reader cerca il dispositivo su tutte le porte e
            % preseleziona quella corretta nel dropdown
            app.listaSeriale.Items = uart_serial_reader(app);

        end

        % Button pushed function: StartProductionButton
        function StartProductionButtonPushed(app, event)
            %selection_datas(app);
            app.mass=zeros(3,1);
            send_datas(app);
        end

        % Button pushed function: ResetMassButton
        function ResetMassButtonPushed(app, event)
            selection_datas(app);
            app.mass=zeros(3,1);

            % Imposta il range del gauge
            try
                app.TimeGauge.Limits = [0   60*app.MassaTotEditField.Value/app.TotalFlowRateEditField.Value];
            catch

                app.TimeGauge.Limits = [0   100];
            end

        end

        % Value changed function: ConnessioneButton
        function ConnessioneButtonValueChanged(app, event)
            value = app.ConnessioneButton.Value;
            if(value==1) %%  pressed
                app.ConnessioneButton.BackgroundColor=[0.39,0.83,0.07];
                connection(app);

            else %% not pressed
                disconnection(app);
                app.ConnessioneButton.BackgroundColor=[1.00,0.41,0.16];

            end
            %ConnessioneButtonValueChanged
        end

        % Value changed function: FunzioneSwitch
        function FunzioneSwitchValueChanged2(app, event)
            selection_datas(app);

        end

        % Button pushed function: ImpostavolumimassimiButton
        function ImpostavolumimassimiButtonPushed(app, event)
            % Imposta il range del gauge
            app.Volume1Gauge.Limits = [0 app.Massa1EditField.Value];

            % Imposta il range del gauge
            app.Volume2Gauge.Limits = [0 app.Massa2EditField.Value];

            % Imposta il range del gauge
            app.VolumeTotGauge.Limits = [0 app.MassaTotEditField.Value];

        end

        % Value changed function: Massa1EditField, Massa2EditField
        function MassaEditFieldValueChanged(app, event)
            % Imposta il range del gauge
            app.Volume1Gauge.Limits = [0 app.Massa1EditField.Value];

            app.MassaTotEditField.Value = app.Massa1EditField.Value+app.Massa2EditField.Value;
            app.VolumeTotGauge.Limits = [0 app.MassaTotEditField.Value];
            try
                q1=  app.Portata1EditField.Value ; % Aggiorna spinner q1
                q2=  app.Portata2EditField.Value ; % Aggiorna spinner q2


                m1 = app.Massa1EditField.Value;
                m2 = app.Massa2EditField.Value;
                app.TimeGauge.Limits = [0, 60*(m1+m2)/(q1+q2)]; % Stima durata totale (s)
            catch
            end
        end

        % Value changed function: MassaTotEditField
        function MassaTotEditFieldValueChanged(app, event)
            app.VolumeTotGauge.Limits = [0 app.MassaTotEditField.Value];
        end

        % Button pushed function: CalibrationDataButton
        function CalibrationDataButtonPushed(app, event)
            C1=app.C1EditField.Value;
            C2=app.C2EditField.Value;
            D1=app.D1EditField.Value;
            D2=app.D2EditField.Value;
            ard_sendControl(app,-1,C1,C2,D1,D2);
        end

        % Button pushed function: ImpostadatiPIDButton
        function ImpostadatiPIDButtonPushed(app, event)
            alfa=app.kp.Value;
            beta=app.ki.Value;
            gamma=app.ki.Value;
            ard_sendControl(app,-2,alfa,beta,gamma,0);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)

            ard_sendControl(app,0,0,0,0,0);
            app.t = 0;

            clearpoints(app.animLine_f1); % Consigliato: svuota anche il grafico

            clearpoints(app.animLine_f2); % Consigliato: svuota anche il grafico

        end

        % Drop down opening function: listaSeriale
        function listaSerialeDropDownOpening(app, event)

            app.listaSeriale.Items=uart_serial_reader(app); %serialportlist("available");


        end

        % Value changed function: SensorModeSwitch
        function SensorModeSwitchValueChanged(app, event)
            %value = app.SensorModeSwitch.Value;

        end

        % Value changed function: StartLoggingButton
        function StartLoggingButtonValueChanged(app, event)

            stato = app.StartLoggingButton.Value;
            if stato
                app.StartLoggingButton.Text = "Logging";
            else
                app.StartLoggingButton.Text = "Not Logging";
            end

            try
                StartStopLogging(app,stato)
            catch ME
                report = getReport(ME, 'extended', 'hyperlinks', 'on');
                uialert(app.UIFigure, report, 'Errore', 'Interpreter', 'html');
            end

        end

        % Button pushed function: SalvaButton
        function SalvaButtonPushed(app, event)
            saveOutputData(app)
        end

        % Value changed function: Portata1EditField
        function Portata1EditFieldValueChanged(app, event)
            Q1 = app.Portata1EditField.Value;
            Q2= app.Portata2EditField.Value;
            [~, ~, TFR, FRR]  =flowConversion(app,Q1,Q2,2);
            app.TotalFlowRateEditField.Value=TFR;
            app.FlowRateRatioEditField.Value=FRR;
        end

        % Value changed function: Portata2EditField
        function Portata2EditFieldValueChanged(app, event)
            Q1 = app.Portata1EditField.Value;
            Q2= app.Portata2EditField.Value;
            [~, ~, TFR, FRR]  =flowConversion(app,Q1,Q2,2);
            app.TotalFlowRateEditField.Value=TFR;
            app.FlowRateRatioEditField.Value=FRR;
        end

        % Value changed function: TotalFlowRateEditField
        function TotalFlowRateEditFieldValueChanged(app, event)
            TFR = app.TotalFlowRateEditField.Value;
            FRR = app.FlowRateRatioEditField.Value;
            [Q1, Q2, ~, ~] = flowConversion(app,TFR, FRR, 1);
            app.Portata1EditField.Value=Q1;
            app.Portata2EditField.Value=Q2;
        end

        % Value changed function: FlowRateRatioEditField
        function FlowRateRatioEditFieldValueChanged(app, event)
            TFR = app.TotalFlowRateEditField.Value;
            FRR = app.FlowRateRatioEditField.Value;
            [Q1, Q2, ~, ~] = flowConversion(app,TFR, FRR, 1);
            app.Portata1EditField.Value=Q1;
            app.Portata2EditField.Value=Q2;
        end

        % Button pushed function: ThemeButton
        function ThemeButtonPushed(app, event)

            if app.theme  == 0
                app.UIFigure.Theme = "light";
                app.theme  = 1;
            else
                app.UIFigure.Theme = "dark";
                app.theme  = 0;
            end

        end

        % Value changed function: Qhigh_1EditField, Qlow_1EditField, 
        % ...and 4 other components
        function CALIBRATION_CALLBACK1(app, event)


            qset  = [app.Qlow_1EditField.Value;         app.Qhigh_1EditField.Value];
            mset  = [app.mLow_hyp_1EditField.Value;     app.mHigh_hyp_1EditField.Value];
            mreal = [app.mLow_real_1EditField.Value;    app.mHigh_real_1EditField.Value];
            [c1,d1]=calibrate_flow_fast(app,qset, mset, mreal);
            app.C1EditField.Value=c1;
            app.D1EditField.Value=d1;
        end

        % Value changed function: Qhigh_2EditField, Qlow_2EditField, 
        % ...and 4 other components
        function CALIBRATION_CALLBACK2(app, event)
            qset  = [app.Qlow_2EditField.Value;         app.Qhigh_2EditField.Value];
            mset  = [app.mLow_hyp_2EditField.Value;     app.mHigh_hyp_2EditField.Value];
            mreal = [app.mLow_real_2EditField.Value;    app.mHigh_real_2EditField.Value];
            [c2,d2]=calibrate_flow_fast(app,qset, mset, mreal);
            app.C2EditField.Value=c2;
            app.D2EditField.Value=d2;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConnessionePanel = uipanel(app.GridLayout);
            app.ConnessionePanel.Title = 'Connessione';
            app.GridLayout5 = uigridlayout(app.ConnessionePanel);
            app.ThemeButton = uibutton(app.GridLayout5, 'push');
            app.ThemeButton.ButtonPushedFcn = createCallbackFcn(app, @ThemeButtonPushed, true);
            app.ThemeButton.Text = 'Theme';
            app.ListaBleLabel_2 = uilabel(app.GridLayout5);
            app.ListaBleLabel_2.Text = 'Lista Seriale';
            app.listaSeriale = uidropdown(app.GridLayout5);
            app.listaSeriale.Items = {'COM'};
            app.listaSeriale.DropDownOpeningFcn = createCallbackFcn(app, @listaSerialeDropDownOpening, true);
            app.listaSeriale.Tooltip = {'Seleziona la porta COM del microcontrollore. L''elenco si aggiorna automaticamente all''apertura.'};
            app.listaSeriale.Value = 'COM';
            app.ConnessioneButton = uibutton(app.GridLayout5, 'state');
            app.ConnessioneButton.ValueChangedFcn = createCallbackFcn(app, @ConnessioneButtonValueChanged, true);
            app.ConnessioneButton.Tooltip = {'Premi per connetterti al dispositivo. Diventa verde se la connessione ha successo. Ripremi per disconnetterti.'};
            app.ConnessioneButton.Text = 'Connessione';
            app.FunzionamentoPanel = uipanel(app.GridLayout);
            app.FunzionamentoPanel.Title = 'Funzionamento';
            app.GridLayout2 = uigridlayout(app.FunzionamentoPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PortateerogateTab = uitab(app.TabGroup);
            app.PortateerogateTab.Title = 'Portate erogate';
            app.GridLayout4_2 = uigridlayout(app.PortateerogateTab);
            app.VolumiErogatiPanel = uipanel(app.GridLayout4_2);
            app.VolumiErogatiPanel.Title = 'Volumi  Erogati';
            app.GridLayout12 = uigridlayout(app.VolumiErogatiPanel);
            app.Volume1EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Volume1EditField.Editable = 'off';
            app.Volume1Gauge = uigauge(app.GridLayout12, 'linear');
            app.Volume1Gauge.Tooltip = {'Massa erogata dalla pompa 1 in tempo reale (g).'};
            app.Volume1GaugeLabel = uilabel(app.GridLayout12);
            app.Volume1GaugeLabel.Text = 'Volume 1 ';
            app.Volume2EditField = uieditfield(app.GridLayout12, 'numeric');
            app.Volume2EditField.Editable = 'off';
            app.Volume2Gauge = uigauge(app.GridLayout12, 'linear');
            app.Volume2Gauge.Tooltip = {'Massa erogata dalla pompa 2 in tempo reale (g).'};
            app.Volume2GaugeLabel = uilabel(app.GridLayout12);
            app.Volume2GaugeLabel.Text = 'Volume 2';
            app.VolumeTotEditField = uieditfield(app.GridLayout12, 'numeric');
            app.VolumeTotEditField.Editable = 'off';
            app.VolumeTotGauge = uigauge(app.GridLayout12, 'linear');
            app.VolumeTotGauge.Tooltip = {'Massa totale erogata (Pompa1 + Pompa2) in tempo reale (g).'};
            app.VolumetotGaugeLabel = uilabel(app.GridLayout12);
            app.VolumetotGaugeLabel.Text = 'Volume tot';
            app.TimeEditField = uieditfield(app.GridLayout12, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.TimeGauge = uigauge(app.GridLayout12, 'linear');
            app.TimeGauge.Tooltip = {'Tempo trascorso dall''avvio della produzione (s). Il limite è calcolato automaticamente dalla massa totale e dalla portata totale.'};
            app.TempoLabel = uilabel(app.GridLayout12);
            app.TempoLabel.Text = 'Tempo';
            app.VolumimassimiPanel = uipanel(app.GridLayout4_2);
            app.VolumimassimiPanel.Title = 'Volumi massimi';
            app.GridLayout11 = uigridlayout(app.VolumimassimiPanel);
            app.Massa1EditFieldLabel = uilabel(app.GridLayout11);
            app.Massa1EditFieldLabel.Text = 'Massa 1';
            app.Massa1EditField = uieditfield(app.GridLayout11, 'numeric');
            app.Massa1EditField.ValueChangedFcn = createCallbackFcn(app, @MassaEditFieldValueChanged, true);
            app.Massa1EditField.Tooltip = {'Massa massima da erogare con la pompa 1 (g). Imposta il limite superiore del gauge Volume 1.'};
            app.Massa1EditField.Value = 50;
            app.Massa2EditFieldLabel = uilabel(app.GridLayout11);
            app.Massa2EditFieldLabel.Text = 'Massa 2';
            app.Massa2EditField = uieditfield(app.GridLayout11, 'numeric');
            app.Massa2EditField.ValueChangedFcn = createCallbackFcn(app, @MassaEditFieldValueChanged, true);
            app.Massa2EditField.Tooltip = {'Massa massima da erogare con la pompa 2 (g). Imposta il limite superiore del gauge Volume 2.'};
            app.Massa2EditField.Value = 50;
            app.MassaTotEditFieldLabel = uilabel(app.GridLayout11);
            app.MassaTotEditFieldLabel.Text = 'Massa Tot';
            app.MassaTotEditField = uieditfield(app.GridLayout11, 'numeric');
            app.MassaTotEditField.ValueChangedFcn = createCallbackFcn(app, @MassaTotEditFieldValueChanged, true);
            app.MassaTotEditField.Editable = 'off';
            app.MassaTotEditField.Tooltip = {'Massa totale massima (g). Imposta il limite del gauge Volume Tot e calcola il tempo stimato di erogazione.'};
            app.MassaTotEditField.Value = 100;
            app.ResetMassButton = uibutton(app.GridLayout11, 'push');
            app.ResetMassButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMassButtonPushed, true);
            app.ResetMassButton.Tooltip = {'Azzera i contatori di massa e riinvia i parametri al dispositivo per ripartire con una nuova erogazione.'};
            app.ResetMassButton.Text = 'Reset Mass';
            app.ImpostavolumimassimiButton = uibutton(app.GridLayout11, 'push');
            app.ImpostavolumimassimiButton.ButtonPushedFcn = createCallbackFcn(app, @ImpostavolumimassimiButtonPushed, true);
            app.ImpostavolumimassimiButton.Tooltip = {'Aggiorna i limiti dei gauge in base ai valori di Massa 1, Massa 2 e Massa Tot inseriti.'};
            app.ImpostavolumimassimiButton.Text = 'Imposta volumi massimi';
            app.GraficoTab = uitab(app.TabGroup);
            app.GraficoTab.Title = 'Grafico';
            app.GridLayout17 = uigridlayout(app.GraficoTab);
            app.UIAxes_values = uiaxes(app.GridLayout17);
            title(app.UIAxes_values, 'Pump 1')
            xlabel(app.UIAxes_values, 'Time(s)')
            ylabel(app.UIAxes_values, 'ml/min')
            zlabel(app.UIAxes_values, 'Z')
            app.UIAxes_values.XLimitMethod = 'padded';
            app.UIAxes_values2 = uiaxes(app.GridLayout17);
            title(app.UIAxes_values2, 'Pump 2')
            xlabel(app.UIAxes_values2, 'Time(s)')
            ylabel(app.UIAxes_values2, 'ml/min')
            zlabel(app.UIAxes_values2, 'Z')
            app.UIAxes_values2.XLimitMethod = 'padded';
            app.Regressione = uitab(app.TabGroup);
            app.Regressione.Title = 'Controllo';
            app.GridLayout10 = uigridlayout(app.Regressione);
            app.FattoreCorrezioneText = uilabel(app.GridLayout10);
            app.FattoreCorrezioneText.Text = 'Calibrazione flussimetri';
            app.pidValuesText = uilabel(app.GridLayout10);
            app.pidValuesText.Text = 'PID Values';
            app.FlowCalibratedtextbfCcdotFlowRawtextbfDLabel = uilabel(app.GridLayout10);
            app.FlowCalibratedtextbfCcdotFlowRawtextbfDLabel.Interpreter = 'latex';
            app.FlowCalibratedtextbfCcdotFlowRawtextbfDLabel.Text = 'Flow Calibrated = \textbf{C} \cdot Flow Raw+\textbf{D}';
            app.utK_petK_iint_0tetaudtauK_dfracdetdtLabel = uilabel(app.GridLayout10);
            app.utK_petK_iint_0tetaudtauK_dfracdetdtLabel.Interpreter = 'latex';
            app.utK_petK_iint_0tetaudtauK_dfracdetdtLabel.Text = '$$u(t) = K_p e(t) + K_i \int_{0}^{t} e(\tau) d\tau + K_d \frac{de(t)}{dt}$$';
            app.Flussimetro1Panel = uipanel(app.GridLayout10);
            app.Flussimetro1Panel.Title = 'Flussimetro 1';
            app.GridLayout19 = uigridlayout(app.Flussimetro1Panel);
            app.LOWFLOWLabel = uilabel(app.GridLayout19);
            app.LOWFLOWLabel.Text = 'LOW FLOW';
            app.HIGHFLOWLabel = uilabel(app.GridLayout19);
            app.HIGHFLOWLabel.Text = 'HIGH FLOW';
            app.FLOWSETRAWLabel = uilabel(app.GridLayout19);
            app.FLOWSETRAWLabel.Text = {'FLOW SET '; '(RAW)'};
            app.Qlow_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.Qlow_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.Qlow_1EditField.Value = 10;
            app.Qhigh_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.Qhigh_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.Qhigh_1EditField.Value = 30;
            app.MassHypLabel = uilabel(app.GridLayout19);
            app.MassHypLabel.Text = 'Mass Hyp';
            app.mLow_hyp_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.mLow_hyp_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.mLow_hyp_1EditField.Value = 30;
            app.mHigh_hyp_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.mHigh_hyp_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.mHigh_hyp_1EditField.Value = 90;
            app.MassRealLabel = uilabel(app.GridLayout19);
            app.MassRealLabel.Text = 'Mass Real';
            app.mLow_real_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.mLow_real_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.mLow_real_1EditField.Value = 30;
            app.mHigh_real_1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.mHigh_real_1EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK1, true);
            app.mHigh_real_1EditField.Value = 90;
            app.alfaLabel = uilabel(app.GridLayout19);
            app.alfaLabel.Text = 'C1';
            app.D1EditFieldLabel = uilabel(app.GridLayout19);
            app.D1EditFieldLabel.Text = 'D1';
            app.C1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.C1EditField.Tooltip = {'Fattore di correzione del flussimetro 1. Calcolato come: Peso stimato dalla macchina / Peso reale dopo pesata. Default = 1 (nessuna correzione).'};
            app.C1EditField.Value = 1;
            app.D1EditField = uieditfield(app.GridLayout19, 'numeric');
            app.PIDSETTINGSPanel = uipanel(app.GridLayout10);
            app.PIDSETTINGSPanel.Title = 'PID SETTINGS';
            app.GridLayout20 = uigridlayout(app.PIDSETTINGSPanel);
            app.alfaLabel_2 = uilabel(app.GridLayout20);
            app.alfaLabel_2.Text = {'Proporzionale'; 'Kp'};
            app.alfaLabel_3 = uilabel(app.GridLayout20);
            app.alfaLabel_3.Text = {'Integrale'; 'Ki'};
            app.alfaLabel_4 = uilabel(app.GridLayout20);
            app.alfaLabel_4.Text = {'Derivativo'; 'Kd'};
            app.kp = uieditfield(app.GridLayout20, 'numeric');
            app.kp.Limits = [0 Inf];
            app.kp.Tooltip = {'Guadagno proporzionale del controllore PID. Aumentarlo rende la risposta più rapida ma può causare oscillazioni.'};
            app.kp.Value = 10;
            app.ki = uieditfield(app.GridLayout20, 'numeric');
            app.ki.Limits = [0 Inf];
            app.ki.Tooltip = {'Guadagno integrale del controllore PID. Elimina l''errore a regime. Valori alti possono causare sovraelongazione.'};
            app.ki.Value = 10;
            app.kd = uieditfield(app.GridLayout20, 'numeric');
            app.kd.Limits = [0 Inf];
            app.kd.Tooltip = {'Guadagno derivativo del controllore PID. Smorzante: riduce le oscillazioni. Sensibile al rumore.'};
            app.kd.Value = 0.1;
            app.rispostaImmediataLabel = uilabel(app.GridLayout20);
            app.rispostaImmediataLabel.Text = 'risposta Immediata';
            app.EliminaErroreStazionarioLabel = uilabel(app.GridLayout20);
            app.EliminaErroreStazionarioLabel.Text = 'Elimina Errore Stazionario';
            app.SmorzamentoOscillazioneLabel = uilabel(app.GridLayout20);
            app.SmorzamentoOscillazioneLabel.Text = 'Smorzamento Oscillazione';
            app.CalibrationDataButton = uibutton(app.GridLayout10, 'push');
            app.CalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationDataButtonPushed, true);
            app.CalibrationDataButton.Tooltip = {'	Invia i fattori di correzione K1 e K2 al microcontrollore. Usare dopo una pesata di calibrazione.'};
            app.CalibrationDataButton.Text = 'Calibration Data';
            app.ImpostadatiPIDButton = uibutton(app.GridLayout10, 'push');
            app.ImpostadatiPIDButton.ButtonPushedFcn = createCallbackFcn(app, @ImpostadatiPIDButtonPushed, true);
            app.ImpostadatiPIDButton.Tooltip = {'Imposta i parametri PID (Kp, Ki, Kd) al microcontrollore.'};
            app.ImpostadatiPIDButton.Text = 'Imposta dati PID';
            app.Flussimetro2Panel = uipanel(app.GridLayout10);
            app.Flussimetro2Panel.Title = 'Flussimetro 2';
            app.GridLayout19_2 = uigridlayout(app.Flussimetro2Panel);
            app.LOWFLOWLabel_2 = uilabel(app.GridLayout19_2);
            app.LOWFLOWLabel_2.Text = 'LOW FLOW';
            app.HIGHFLOWLabel_2 = uilabel(app.GridLayout19_2);
            app.HIGHFLOWLabel_2.Text = 'HIGH FLOW';
            app.FLOWSETRAWLabel_2 = uilabel(app.GridLayout19_2);
            app.FLOWSETRAWLabel_2.Text = {'FLOW SET'; '(RAW)'};
            app.Qlow_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.Qlow_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.Qlow_2EditField.Value = 10;
            app.Qhigh_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.Qhigh_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.Qhigh_2EditField.Value = 30;
            app.MassHypLabel_2 = uilabel(app.GridLayout19_2);
            app.MassHypLabel_2.Text = 'Mass Hyp';
            app.mLow_hyp_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.mLow_hyp_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.mLow_hyp_2EditField.Value = 30;
            app.mHigh_hyp_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.mHigh_hyp_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.mHigh_hyp_2EditField.Value = 90;
            app.MassRealLabel_2 = uilabel(app.GridLayout19_2);
            app.MassRealLabel_2.Text = 'Mass Real';
            app.mLow_real_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.mLow_real_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.mLow_real_2EditField.Value = 30;
            app.mHigh_real_2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.mHigh_real_2EditField.ValueChangedFcn = createCallbackFcn(app, @CALIBRATION_CALLBACK2, true);
            app.mHigh_real_2EditField.Value = 90;
            app.C2Label = uilabel(app.GridLayout19_2);
            app.C2Label.Text = 'C2';
            app.C2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.C2EditField.Tooltip = {'Fattore di correzione del flussimetro 1. Calcolato come: Peso stimato dalla macchina / Peso reale dopo pesata. Default = 1 (nessuna correzione).'};
            app.C2EditField.Value = 1;
            app.D2Label = uilabel(app.GridLayout19_2);
            app.D2Label.Text = 'D2';
            app.D2EditField = uieditfield(app.GridLayout19_2, 'numeric');
            app.LaunchPanel = uipanel(app.GridLayout2);
            app.LaunchPanel.Title = 'Launch';
            app.GridLayout6 = uigridlayout(app.LaunchPanel);
            app.FunzioneSwitch = uiswitch(app.GridLayout6, 'rocker');
            app.FunzioneSwitch.Items = {'Portate', 'TFR - FRR'};
            app.FunzioneSwitch.ItemsData = {'0', '1'};
            app.FunzioneSwitch.ValueChangedFcn = createCallbackFcn(app, @FunzioneSwitchValueChanged2, true);
            app.FunzioneSwitch.Tooltip = {'Portate: inserisci Portata 1 e Portata 2 direttamente in ml/min. TFR-FRR: inserisci la portata totale e il rapporto tra le pompe.'};
            app.FunzioneSwitch.Value = '0';
            app.portate_2 = uipanel(app.GridLayout6);
            app.portate_2.Enable = 'off';
            app.GridLayout3_2 = uigridlayout(app.portate_2);
            app.TotalFlowRateLabel = uilabel(app.GridLayout3_2);
            app.TotalFlowRateLabel.Text = 'Total Flow Rate ';
            app.FlowRateRatioLabel = uilabel(app.GridLayout3_2);
            app.FlowRateRatioLabel.Text = 'Flow Rate Ratio';
            app.TotalFlowRateEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.TotalFlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @TotalFlowRateEditFieldValueChanged, true);
            app.TotalFlowRateEditField.Tooltip = {'Portata totale (Pompa1 + Pompa2) in ml/min.'};
            app.FlowRateRatioEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.FlowRateRatioEditField.ValueChangedFcn = createCallbackFcn(app, @FlowRateRatioEditFieldValueChanged, true);
            app.FlowRateRatioEditField.Tooltip = {'Rapporto Portata1 / Portata2. Es: 2 significa che la pompa 1 eroga il doppio della pompa 2.'};
            app.SensorModeSwitch = uiswitch(app.GridLayout6, 'rocker');
            app.SensorModeSwitch.Items = {'NO PID', 'PID'};
            app.SensorModeSwitch.ItemsData = [0 2];
            app.SensorModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorModeSwitchValueChanged, true);
            app.SensorModeSwitch.Tooltip = {'Senza Sensore: controllo in anello aperto. Sensore: abilita il feedback dal sensore di flusso.'};
            app.SensorModeSwitch.Value = 2;
            app.StartLoggingButton = uibutton(app.GridLayout6, 'state');
            app.StartLoggingButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingButtonValueChanged, true);
            app.StartLoggingButton.Tooltip = {'Avvia/ferma la registrazione dati su file CSV temporaneo. Ricordati di salvare con il pulsante "Salva" prima di chiudere.'};
            app.StartLoggingButton.Text = 'Start Logging';
            app.STOPButton = uibutton(app.GridLayout6, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Tooltip = {'Ferma immediatamente entrambe le pompe e azzera il timer e i grafici.'};
            app.STOPButton.Text = 'STOP';
            app.portate = uipanel(app.GridLayout6);
            app.GridLayout3 = uigridlayout(app.portate);
            app.Flusso1Label = uilabel(app.GridLayout3);
            app.Flusso1Label.Text = 'Portata 1';
            app.Flusso2Label = uilabel(app.GridLayout3);
            app.Flusso2Label.Text = 'Portata 2';
            app.Portata1EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Portata1EditField.ValueChangedFcn = createCallbackFcn(app, @Portata1EditFieldValueChanged, true);
            app.Portata1EditField.Tooltip = {'Portata della pompa 1 in ml/min.'};
            app.Portata2EditField = uieditfield(app.GridLayout3, 'numeric');
            app.Portata2EditField.ValueChangedFcn = createCallbackFcn(app, @Portata2EditFieldValueChanged, true);
            app.Portata2EditField.Tooltip = {'Portata della pompa 2 in ml/min.'};
            app.SalvaButton = uibutton(app.GridLayout6, 'push');
            app.SalvaButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaButtonPushed, true);
            app.SalvaButton.Tooltip = {'Apre una finestra per salvare i dati di log in formato Excel (.xlsx) o CSV. I dati vengono letti dal file temporaneo creato durante il logging.'};
            app.SalvaButton.Text = 'Salva';
            app.StartProductionButton = uibutton(app.GridLayout6, 'push');
            app.StartProductionButton.ButtonPushedFcn = createCallbackFcn(app, @StartProductionButtonPushed, true);
            app.StartProductionButton.Tooltip = {'Invia i parametri al microcontrollore e avvia il dosaggio. Assicurati di aver impostato portate e masse prima di premere.'};
            app.StartProductionButton.Text = {'Start '; 'Production'};
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout18 = uigridlayout(app.Panel);
            app.Lamp = uilamp(app.GridLayout18);
            app.Lamp.Tooltip = {'Verde: dati ricevuti correttamente. Giallo: pacchetto seriale desincronizzato, possibile rumore sul canale.'};
            app.UITable = uitable(app.GridLayout18);
            app.UITable.ColumnName = {'dt'; 'q1'; 'V1'; 'q2'; 'V2'; 'f1'; 'f2'; 'm1'; 'm2'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accellerator_ottobre2026

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end